classdef guimain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIMain     matlab.ui.Figure
        MenuFile    matlab.ui.container.Menu
        Map         matlab.ui.control.UIAxes
        DebugLabel  matlab.ui.control.Label
    end

    properties (Access = public)% GNSS
        % VERNEUIL mlat=48.97908&mlon=1.97547
        userLatitude        = 48.97908       % temporary : receiver
        userLongitude       = 1.97547        % temporary : receiver
        
        % Map
        mapFolder           = 'D:\GitHub\LeONAR_dev\tiles\' % temporary : user preference
        mapCurrentZoom
        mapMinZoom
        mapMaxZoom
        mapCenterTile
        mapSize
        mapTiles
        
        % Callbacks
        cbMouseWheelCount   = 0
        mapClicked          = false
        mapClickedCoords    = [0 0]
        mapMotionCounter    = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.mapMinZoom,app.mapMaxZoom] = GetMinMaxZoomFromTilesFolder(app.mapFolder);
            
            % Starting fully zoomed out
            % (TODO: temporary, waiting for user configuration file storing last zoom level)
            app.mapCurrentZoom = app.mapMaxZoom; % app.mapMinZoom;
            
            % Updating map size
            app.mapSize = DetermineMapSize(app.Map.Position(3),app.Map.Position(4));
            
            % Initializing map
            UpdateMapDisplay(app);
        end

        % Window button down function: GUIMain
        function GUIMainWindowButtonDownFcn(app, event)
            [source,coords_source,~] = leonar_WindowButtonDownFcn(app,event);
            switch source
                case 'Map'
                    app.mapClicked          = true;
                    app.mapClickedCoords    = coords_source;
                    app.DebugLabel.Text     = num2str(app.mapClickedCoords);
                otherwise
                    app.mapClicked          = false;
            end
        end

        % Window scroll wheel function: GUIMain
        function GUIMainWindowScrollWheel(app, event)
            leonar_WindowScrollWheel(app,event);
        end

        % Window button up function: GUIMain
        function GUIMainWindowButtonUp(app, event)
            app.mapClicked          = false;
            app.mapMotionCounter    = 0;
        end

        % Size changed function: GUIMain
        function GUIMainSizeChanged(app, event)
            position = app.GUIMain.Position;
            % TODO : update mapTiled projection
            % TODO : resize children
        end

        % Window button motion function: GUIMain
        function GUIMainWindowButtonMotion(app, event)
            leonar_WindowButtonMotion(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIMain = uifigure('Visible', 'off');
            app.GUIMain.Name = 'LeONAR';
            app.GUIMain.SizeChangedFcn = createCallbackFcn(app, @GUIMainSizeChanged, true);
            app.GUIMain.WindowButtonDownFcn = createCallbackFcn(app, @GUIMainWindowButtonDownFcn, true);
            app.GUIMain.WindowButtonUpFcn = createCallbackFcn(app, @GUIMainWindowButtonUp, true);
            app.GUIMain.WindowButtonMotionFcn = createCallbackFcn(app, @GUIMainWindowButtonMotion, true);
            app.GUIMain.WindowScrollWheelFcn = createCallbackFcn(app, @GUIMainWindowScrollWheel, true);
            app.MenuFile = uimenu(app.GUIMain);
            app.MenuFile.Text = 'File';
            app.Map = uiaxes(app.GUIMain);
            title(app.Map, '')
            xlabel(app.Map, '')
            ylabel(app.Map, '')
            app.Map.XTick = [];
            app.Map.XTickLabel = '';
            app.Map.YTick = [];
            app.DebugLabel = uilabel(app.GUIMain);
            app.DebugLabel.Text = 'Debug';
            % Show the figure after all components are created
            app.GUIMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guimain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIMain)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIMain)
        end
    end
end